classdef HW1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        lab_bl     matlab.ui.control.Label
        lab_br     matlab.ui.control.Label
        lab_tl     matlab.ui.control.Label
        lab_tr     matlab.ui.control.Label
        lab_title  matlab.ui.control.Label
        but_br     matlab.ui.control.Button
        but_bl     matlab.ui.control.Button
        but_tr     matlab.ui.control.Button
        but_tl     matlab.ui.control.Button
    end

    
    properties (Access = private)
        m_img; % Image selected to be displayed by user
        m_IconParts % cell array contains 4 slices of the image as the icon of the 4 buttons
        m_RedSeq; % the order the button turning red
        m_NewRedIdx; % an int index of the newly turned red button
        m_HitDelay; % double array recording the time it takes for the user to hit each red button after it turns red
    end
    
    properties (Access = public)
        RedButtonPressed % flag checking if the button turned red is pressed. Must be public so the waitfor function can check its value
    end
    
    methods (Access = private)
        function m_SetButtonIcon(app)
            % Prompts the user to select an image to be used as the icons
            % of each button.
            % The selected image will be splitted into 4 parts and stored
            % into cell array app.m_IconParts as double arrays

            [fName, pName] = uigetfile({'*.png'; '*.jpeg'; '*.jpg'}, "MultiSelect", "off");
            app.m_img = imread([pName  fName]);
            [nRow, nCol, ~] = size(app.m_img);
            imgVrtBoundaryIdx = floor(nRow / 2);
            imgHozBoundaryIdx = floor(nCol / 2);
            
            app.m_IconParts{1} = app.m_img(1 : imgVrtBoundaryIdx, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{2} = app.m_img(1 :imgVrtBoundaryIdx, imgHozBoundaryIdx : end, :);
            app.m_IconParts{3} = app.m_img(imgVrtBoundaryIdx : end, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{4} = app.m_img(imgVrtBoundaryIdx : end, imgHozBoundaryIdx : end, :);
        end
        
        function m_PlayGame(app)
            % Main game code. Controls which button turns red and measures
            % the time it takes for the user to press the button after it turns red,
            % storing the time into property m_HitDelay.

            buttons = {app.but_tl, app.but_tr, app.but_bl, app.but_br};
            labels = {app.lab_tl, app.lab_tr, app.lab_bl, app.lab_br};
            for hit = 1:4
                pause(1.5); % buttons turn red every 1.5 seconds
                app.m_NewRedIdx = app.m_RedSeq(hit); % update the index of the new red button
                buttons{app.m_NewRedIdx}.BackgroundColor = 'r'; % turn the indexed button red
                t0 = tic; % get the current time stamp
                waitfor(app, 'RedButtonPressed', true); % wait for the correct red button's callback function modifies the flag
                app.RedButtonPressed = false; % reset the flag to false after the correct button is pressed
                
                app.m_HitDelay(hit) = toc(t0); % measure the time it took for the user to press the new red button
                app.m_DisplayHitDelay(labels, hit); % call private function to display the elasped time with ui label
                
                buttons{app.m_NewRedIdx}.Icon = app.m_IconParts{app.m_NewRedIdx};
            end
        end

        function m_MatchButtonPressed(app, pressedIdx)
            % This function is called by the callback function of each
            % button. Each button has its unique index:
            %       Top_Left button = 1
            %       Top_Rigth button = 2
            %       Botton Left button = 3
            %       Botton Right button = 4
            % If the button's callback funciton calls this function when
            % pressIdx matches app.m_NewRedIdx, set app.RedButtonPressed
            % flag true. Otherwise, nothing happens.
            if pressedIdx == app.m_NewRedIdx
                app.RedButtonPressed = true;
            end
        end
        
        function m_DisplayHitDelay(app, labels, hit)
            labels{app.m_NewRedIdx}.Text = sprintf('%.3f', app.m_HitDelay(hit));
            labels{app.m_NewRedIdx}.Visible = 'On';
        end
        
        function m_EndGame(app)
            app.m_BlinkBackgroundRed();
            app.m_DropTitle();
            if sum(app.m_HitDelay) < 2000
                pause(4);
                close(app.UIFigure);
            end
        end
        
        function m_BlinkBackgroundRed(app)
            for i = 1:2
                app.UIFigure.Color = 'r';
                pause(0.2);
                app.UIFigure.Color = 'w';
                pause(0.2);
            end
            app.UIFigure.Color = 'r';
        end
        
        function m_DropTitle(app)
            app.lab_title.Text = sprintf("You took %.3f seconds in total!", sum(app.m_HitDelay));
            app.lab_title.Position(3) = 400;
            while app.lab_title.Position(2) > 15
                app.lab_title.Position(2) = app.lab_title.Position(2) -10;
                pause(0.01);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Program enters here

            app.m_SetButtonIcon();
            app.RedButtonPressed = false; % initialize flag to false
            app.m_RedSeq = randperm(4); % get the random order for the button to turn red
            app.m_PlayGame(); % start main game
            app.m_EndGame();% display score
        end

        % Button pushed function: but_tl
        function but_tlButtonPushed(app, event)
            buttonIdx = 1;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_tr
        function but_trButtonPushed(app, event)
            buttonIdx = 2;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_bl
        function but_blButtonPushed(app, event)
            buttonIdx = 3;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_br
        function but_brButtonPushed(app, event)
            buttonIdx = 4;
            app.m_MatchButtonPressed(buttonIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.but_tl = uibutton(app.UIFigure, 'push');
            app.but_tl.ButtonPushedFcn = createCallbackFcn(app, @but_tlButtonPushed, true);
            app.but_tl.Text = '';
            app.but_tr = uibutton(app.UIFigure, 'push');
            app.but_tr.ButtonPushedFcn = createCallbackFcn(app, @but_trButtonPushed, true);
            app.but_tr.Text = '';
            app.but_bl = uibutton(app.UIFigure, 'push');
            app.but_bl.ButtonPushedFcn = createCallbackFcn(app, @but_blButtonPushed, true);
            app.but_bl.Text = '';
            app.but_br = uibutton(app.UIFigure, 'push');
            app.but_br.ButtonPushedFcn = createCallbackFcn(app, @but_brButtonPushed, true);
            app.but_br.Text = '';
            app.lab_title = uilabel(app.UIFigure);
            app.lab_title.Text = 'B10831020: Whack-a-mole';
            app.lab_tr = uilabel(app.UIFigure);
            app.lab_tr.Visible = 'off';
            app.lab_tl = uilabel(app.UIFigure);
            app.lab_tl.Visible = 'off';
            app.lab_br = uilabel(app.UIFigure);
            app.lab_br.Visible = 'off';
            app.lab_bl = uilabel(app.UIFigure);
            app.lab_bl.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end